classdef Model_App_Latest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        AppTitle                        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        Clear_Main                      matlab.ui.control.Button
        Stop                            matlab.ui.control.Button
        Start                           matlab.ui.control.Button
        SaveBox                         matlab.ui.control.CheckBox
        SaveWav                         matlab.ui.control.CheckBox
        VolumeKnobLabel                 matlab.ui.control.Label
        VolumeKnob                      matlab.ui.control.Knob
        ElapsedTimesEditFieldLabel      matlab.ui.control.Label
        ElapsedTime                     matlab.ui.control.NumericEditField
        GainKnobLabel                   matlab.ui.control.Label
        GainKnob                        matlab.ui.control.Knob
        NormalizeCheckBox               matlab.ui.control.CheckBox
        FiltersTab                      matlab.ui.container.Tab
        OKButton                        matlab.ui.control.Button
        FiltersAppliedListBoxLabel      matlab.ui.control.Label
        FiltersAppliedListBox           matlab.ui.control.ListBox
        TabGroup2                       matlab.ui.container.TabGroup
        LowPassTab                      matlab.ui.container.Tab
        FstopEditFieldLabel             matlab.ui.control.Label
        FstopLPF                        matlab.ui.control.NumericEditField
        FpassEditFieldLabel             matlab.ui.control.Label
        FpassLPF                        matlab.ui.control.NumericEditField
        CreateFilters                   matlab.ui.control.Button
        DesignMethodDropDown_8Label_2   matlab.ui.control.Label
        Design_method_2                 matlab.ui.control.DropDown
        HighPassTab                     matlab.ui.container.Tab
        CreateHPF                       matlab.ui.control.Button
        FstopEditField_2Label           matlab.ui.control.Label
        FstopHPF                        matlab.ui.control.NumericEditField
        FpassEditField_2Label           matlab.ui.control.Label
        FpassHPF                        matlab.ui.control.NumericEditField
        DesignMethodDropDown_8Label_3   matlab.ui.control.Label
        Design_method_3                 matlab.ui.control.DropDown
        BandPassTab                     matlab.ui.container.Tab
        CreateBPF                       matlab.ui.control.Button
        Fstop1EditFieldLabel            matlab.ui.control.Label
        Fstop1BPF                       matlab.ui.control.NumericEditField
        Fpass2Label                     matlab.ui.control.Label
        Fpass2BPF                       matlab.ui.control.NumericEditField
        Fstop2BPF                       matlab.ui.control.NumericEditField
        Fstop2EditFieldLabel            matlab.ui.control.Label
        Fpass1BPF                       matlab.ui.control.NumericEditField
        Fpass1EditField_2Label          matlab.ui.control.Label
        DesignMethodDropDown_8Label_4   matlab.ui.control.Label
        Design_method_4                 matlab.ui.control.DropDown
        NotchTab                        matlab.ui.container.Tab
        CreateNF                        matlab.ui.control.Button
        FnotchEditFieldLabel            matlab.ui.control.Label
        FnotchEditField                 matlab.ui.control.NumericEditField
        Design_Method                   matlab.ui.control.Label
        Design_method                   matlab.ui.control.DropDown
        BandwidthEditFieldLabel         matlab.ui.control.Label
        BandwidthEditField              matlab.ui.control.NumericEditField
        UIFiltSpec                      matlab.ui.control.UIAxes
        Clear_Filter                    matlab.ui.control.Button
        FiltersCascadedLampLabel        matlab.ui.control.Label
        FiltersCascadedLamp             matlab.ui.control.Lamp
        EqualizerTab                    matlab.ui.container.Tab
        EqualizerSpec                   matlab.ui.control.UIAxes
        GraphicEQPanel                  matlab.ui.container.Panel
        HzSliderLabel                   matlab.ui.control.Label
        EQGain_1                        matlab.ui.control.Slider
        HzSlider_2Label                 matlab.ui.control.Label
        EQGain_2                        matlab.ui.control.Slider
        HzSlider_3Label                 matlab.ui.control.Label
        EQGain_3                        matlab.ui.control.Slider
        HzSlider_4Label                 matlab.ui.control.Label
        EQGain_4                        matlab.ui.control.Slider
        HzSlider_5Label                 matlab.ui.control.Label
        EQGain_5                        matlab.ui.control.Slider
        HzSlider_6Label                 matlab.ui.control.Label
        EQGain_6                        matlab.ui.control.Slider
        HzSlider_7Label                 matlab.ui.control.Label
        EQGain_7                        matlab.ui.control.Slider
        HzSlider_8Label                 matlab.ui.control.Label
        EQGain_8                        matlab.ui.control.Slider
        HzLabel                         matlab.ui.control.Label
        EQGain_9                        matlab.ui.control.Slider
        HzSlider_10Label                matlab.ui.control.Label
        EQGain_10                       matlab.ui.control.Slider
        VisualizeButton                 matlab.ui.control.Button
        Clear_EQ                        matlab.ui.control.Button
        InputTab                        matlab.ui.container.Tab
        InputChannel                    matlab.ui.container.ButtonGroup
        LeftButton                      matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRate                    matlab.ui.control.NumericEditField
        AudioDevice                     matlab.ui.container.ButtonGroup
        MicButton                       matlab.ui.control.RadioButton
        LineInButton                    matlab.ui.control.RadioButton
        Record_Driver_Tag               matlab.ui.control.Label
        Record_Driver                   matlab.ui.control.DropDown
        OutputTab                       matlab.ui.container.Tab
        DiagnosticsParametersPanel      matlab.ui.container.Panel
        TotalNumberofOverrunSamplesEditFieldLabel  matlab.ui.control.Label
        TNOS                            matlab.ui.control.NumericEditField
        TotalSecondsOverrunEditFieldLabel  matlab.ui.control.Label
        TSO                             matlab.ui.control.NumericEditField
        RawAudioFilenameEditFieldLabel  matlab.ui.control.Label
        RAF                             matlab.ui.control.EditField
        ProcessedAudioFilenameEditFieldLabel  matlab.ui.control.Label
        PAF                             matlab.ui.control.EditField
        OutputFilesPanel                matlab.ui.container.Panel
        RawOut                          matlab.ui.control.CheckBox
        ProcessedOut                    matlab.ui.control.CheckBox
        OutputFolderEditFieldLabel      matlab.ui.control.Label
        OutputFolderEditField           matlab.ui.control.EditField
        SelectFolderButton              matlab.ui.control.Button
        ViewTab                         matlab.ui.container.Tab
        Analyze                         matlab.ui.control.Button
        Import                          matlab.ui.control.Button
        ImportFilenameLabel             matlab.ui.control.Label
        ImportFilename                  matlab.ui.control.EditField
        SpectrumType                    matlab.ui.container.ButtonGroup
        PowerSpectralDensityButton      matlab.ui.control.RadioButton
        PowerButton                     matlab.ui.control.RadioButton
        SpectrogramButton               matlab.ui.control.Button
        OverlapLabel                    matlab.ui.control.Label
        Overlap                         matlab.ui.control.NumericEditField
        WindowFunctions                 matlab.ui.container.ButtonGroup
        HanningButton                   matlab.ui.control.RadioButton
        HammingButton                   matlab.ui.control.RadioButton
        KasierButton                    matlab.ui.control.RadioButton
        RectangularButton               matlab.ui.control.RadioButton
        BartlettButton                  matlab.ui.control.RadioButton
        BlackmanButton                  matlab.ui.control.RadioButton
        BlackmanharrisButton            matlab.ui.control.RadioButton
        BarthannwinButton               matlab.ui.control.RadioButton
        FreqLimitkHzSliderLabel         matlab.ui.control.Label
        FreqLimitkHzSlider              matlab.ui.control.Slider
        Panel                           matlab.ui.container.Panel
        WindowsLengthDropDownLabel      matlab.ui.control.Label
        WindowsLengthDropDown           matlab.ui.control.DropDown
        WindowsLengthEditField          matlab.ui.control.NumericEditField
        Clear_View                      matlab.ui.control.Button
        ExportasImageButton             matlab.ui.control.Button
        ReadyLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %       Parameters
        
        Fs = 44100;         %   Recording Sampling Rate
        Fs_Import = '';     %   Sampling Rate of Imported Audio Files
        
        dt
        
        
        Volume = 1 ;


        %       Filter Sys object
        equalizer
        FilterDesignMethod = 'butter';
        FilterMatchExactly = 'stopband';
        FilterBank = {};
        %   cascade filter
        FC
        
        %       Filters counter
        filtercount = 1;
        lpfc = 1;
        hpfc = 1;
        bpfc = 1;
        nfc  = 1;
        
        %       Visualization
        timeScope
        Spectrum
        
        %       Acquisition Object
        deviceReader
        Sink
        
        %       Input Options
        driver
        
              
        %       Data Obtained
        audioFromDevice
        audioFilt
        audioImport
        
        %       window parameter
        winlength = 1024;        
        spectrumtype = 'psd';
        overlap = 50;
        windowtype = window(@hann,1024);
        
        spectroaxes     %   Axes handle of Spectrogram
        spectrofig      %   Figure handle of Spectrogram in View Section
        audiotimevec    %   Figure handle of time plot in View Section
        
        freqaxis
        
        %       Output Save Files Location
        %   Save file name
        
        savefile = 'Output Data'
        
        %   Save file path
        savepath = strcat(getenv('USERPROFILE'),'\Desktop\');
        
        % View file input paths
        lastpath = 0;
        
        inipath = strcat(getenv('USERPROFILE'),'\Desktop\');
        
        
    end


    methods (Access = private)
        
        function clearEQparameters(app)
            
            app.EQGain_1.Value = 0;
            app.EQGain_2.Value = 0;
            app.EQGain_3.Value = 0;
            app.EQGain_4.Value = 0;
            app.EQGain_5.Value = 0;
            app.EQGain_6.Value = 0;
            app.EQGain_7.Value = 0;
            app.EQGain_8.Value = 0;
            app.EQGain_9.Value = 0;
            app.EQGain_10.Value = 0;
            app.equalizer.Gains(1:end) = 0;
            %
            
        end
        
        
        function filterdesign(app,event_title)
            
            Astop = 60;     % Stopband Attenuation (dB)
            Apass = 1;      % Passband Ripple (dB)
            
            %   check if it is from startup or not
            %   if from startup, then provide a direction to recreate the filters
            
            if nargin == 1
                event_title =  'Band Pass';
                
            end
            
            
            switch event_title
                
                case 'Low Pass'
                    
                    Fstop = app.FstopLPF.Value;
                    Fpass = app.FpassLPF.Value;
                    
                    h = fdesign.lowpass('fp,fst,ap,ast', Fpass, Fstop, Apass, Astop, app.Fs);
                    
                    
                    app.FilterBank{app.filtercount} = design(h,app.FilterDesignMethod ,'MatchExactly', app.FilterMatchExactly,'SystemObject', true);
                    app.FiltersAppliedListBox.Items(app.filtercount) = strcat('LowPass',{' '},num2str(app.lpfc));
                    app.lpfc = app.lpfc+1;
                    
                case 'High Pass'
                    
                    Fstop = app.FstopHPF.Value;
                    Fpass = app.FpassHPF.Value;
                    
                    h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass, app.Fs);
                    
                    app.FilterBank{app.filtercount} = design(h,app.FilterDesignMethod ,'MatchExactly',app.FilterMatchExactly,'SystemObject', true);
                    app.FiltersAppliedListBox.Items(app.filtercount) = strcat('HighPass',{' '},num2str(app.hpfc));
                    app.hpfc = app.hpfc + 1;
                    
                case 'Band Pass'
                    
                    Fstop1 = app.Fstop1BPF.Value;
                    Fpass1 = app.Fpass1BPF.Value;
                    Fpass2 = app.Fpass2BPF.Value;
                    Fstop2 = app.Fstop2BPF.Value;
                    Astop1 = Astop;
                    Astop2 = Astop;
                    
                    h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2', Fstop1, Fpass1, ...
                        Fpass2, Fstop2, Astop1, Apass, Astop2, app.Fs);
                    
                    app.FilterBank{app.filtercount} = design(h,app.FilterDesignMethod,'MatchExactly',app.FilterMatchExactly,'SystemObject',true);
                    app.FiltersAppliedListBox.Items(app.filtercount) = strcat('BandPass',{' '},num2str(app.bpfc));
                    app.bpfc = app.bpfc + 1;
                    
                case 'Notch'
                    
                    Fnotch = app.FnotchEditField.Value;  % Notch Frequency
                    BW = app.BandwidthEditField.Value;
                    Apass  = 0.01;   % Bandwidth Attenuation


                    % Calculate the coefficients using the IIRNOTCHPEAK function.
                    [b, a] = iirnotch(Fnotch/(app.Fs/2), BW/(app.Fs/2), Apass);
                    app.FilterBank{app.filtercount} = dsp.IIRFilter( ...
                        'Structure', 'Direct form II', ...
                        'Numerator', b, ...
                        'Denominator', a);
                    
                    app.FiltersAppliedListBox.Items(app.filtercount) = strcat('Notch',{' '},num2str(app.nfc));
                    app.nfc = app.nfc + 1;
                    
            end
            
            app.filtercount = app.filtercount + 1;
            
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function Model_OpeningFcn(app)
            %           Parameters
            
            clc;
            clear property
            app.ReadyLamp.Color = 'red';
            app.FiltersCascadedLamp.Color = 'red';
            
            
            %   Append names to tags associated
            
            FieldNames = fieldnames(app);
            for i = 1:length(FieldNames)
                app.(FieldNames{i}).Tag = FieldNames{i};
            end
            
            
            %   app.Fs = 44100;                          Suggested Sampling Rate in Hz
            app.dt=1/app.Fs;                             % sampling time in seconds


            %   create visualization panels
            app.timeScope = dsp.TimeScope('SampleRate',app.Fs, ...
                'BufferLength',2E+10, ...
                'ShowLegend',true, ...
                'TimeSpanSource','Property',...
                'YLimits',[-1 1], ...
                'ChannelNames',{'Raw','Filtered'},...
                'Name','Sound Signal',...
                'Title','Sound Signal');
           
            app.Spectrum = dsp.SpectrumAnalyzer('SampleRate',app.Fs,...
                'ShowLegend',true, ...
                'ChannelNames',{'Raw','Filtered'},...
                'PlotAsTwoSidedSpectrum',false,...
                'RBWSource','Auto',...
                'SpectrumType','RMS');
            app.Spectrum.SpectralAverages = 1;
            app.Spectrum.PowerUnits = 'dBW';
            
            
            %   create filters
            
            app.equalizer = graphicEQ;
            Clear_FilterButtonPushed(app);


            %   create audio acquisiton object
            app.deviceReader = audioDeviceReader('SampleRate',app.Fs,'Driver','DirectSound','ChannelMappingSource','Property');
            
            %   Left mono as default
            
            app.deviceReader.ChannelMapping = 1;
            
            
            %   check for line in (auto mode)
            
            
            listDevices = getAudioDevices(app.deviceReader);
            checkLineIn = strcmp(listDevices,'Line In (Realtek High Definition Audio)');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  PLUG IN AND CHOOSE AUDIO DEVICES TO USE BEFORE CREATION  %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if (any(checkLineIn))
                app.deviceReader.Device = 'Line In (Realtek High Definition Audio)';
                app.LineInButton.Enable = 'on';
                app.LineInButton.Value = 1;
                
            end
                              
            %   create Buffer for storage
            app.Sink = dsp.SignalSink;
            
            %   Dock figure in panels
            %   set(0, 'DefaultFigureWindowStyle', 'docked');
            app.ReadyLamp.Color = 'green';
            
        end

        % Button pushed function: Clear_Main
        function Clear_Main_Callback(app, event)
            
            try
                
                if isLocked(app.deviceReader)
                    release(app.deviceReader);
                    app.ReadyLamp.Color = 'green';
                    
                end
                
                if isLocked(app.timeScope)
                    release(app.timeScope);
                    release(app.Spectrum);
                end
                
                reset(app.Sink);
                reset(app.Spectrum);
                reset(app.timeScope);
                
                %   Internal State Reset
                app.Record_Driver.Value = 'DirectSound';
                Record_Driver_Callback(app,event)
                
                
                %   Values and Parameters Reset
                %   vol & gain
                app.VolumeKnob.Value = 1;
                
                
                app.GainKnob.Value = 1;
                VolumeKnobValueChanging(app,event);
                
                app.SamplingRate.Value = 44100;
                app.Fs = 44100;
                
                
                %   save parameters
                app.SaveBox.Value = 0;
                app.SaveWav.Value = 0;
                SaveWavValueChanged(app, event)
                
                app.RAF.Value = 'Raw';
                app.PAF.Value = 'Processed';
                
                
                %   diagnostic parameters
                app.TNOS.Value = 0;
                app.TSO.Value = 0;
                app.ElapsedTime.Value = 0;
                
                
                %   input choices
                
                app.LeftButton.Value = 1;
                InputChannelSelectionChanged(app, event)
                
               
            catch ME
                
                warning('Problem Found On clearing');
                
            end
            
        end

        % Value changed function: Record_Driver
        function Record_Driver_Callback(app, event)
            
            app.deviceReader.Driver = app.Record_Driver.Value;
            
        end

        % Button pushed function: Start
        function Start_Callback(app, event)
            
            
            % signal acquisition setup
            setup(app.deviceReader);
            totalOverrun = 0;
            
            app.ReadyLamp.Color = 'red';
            
            
            % stream processing loop
            tic
            
            while isLocked(app.deviceReader)
                %     capture audio from device
                [app.audioFromDevice,numOverrun] = app.deviceReader();
                totalOverrun =numOverrun + totalOverrun;
                
                %   Processing goes here
                
                %     include any filters as in this form
                
                app.audioFilt = app.equalizer(app.audioFromDevice);
                app.audioFilt = app.FC(app.audioFilt);
                
                
                %     amplification
                app.audioFromDevice = app.Volume.*app.audioFromDevice;
                app.audioFilt = app.Volume.*app.audioFilt;
                
                %     display it
                app.timeScope([app.audioFromDevice app.audioFilt]);
                
                
                %   Store it
                %   First Column    : Original Audio
                %   Second Column   : Filtered Audio
                
                app.Sink([app.audioFromDevice app.audioFilt]);
                
            end
            
            % diagnostic info lines part 1
            app.ElapsedTime.Value = toc;
            
                        
            % Stop the oscilloscope
            release(app.timeScope);
            
            
            %   Save memory
            clear app.audioFilt
            clear app.audioFromDevice
            
            % Extract from buffer to prevent memory overloading
            
            if (app.SaveBox.Value) || (app.SaveWav.Value)
                audioOut = app.Sink.Buffer;
                
                if (app.NormalizeCheckBox.Value)
                    audioOut = normalize(audioOut);
                    
                end
            end
            
            release(app.Sink);
            
            
            % Audio Samples will be exported to current working directory if the
            % save enabled box is checked
            
            if (app.SaveBox.Value)
                
                % timestamps generation only if necessary
                % this is to save time not processing alot codes
                N=(length(audioOut(:,1)));              % number samples in record
                T=(length(audioOut(:,1))-1)*app.dt;     % sample time, s
                timeStamps=(linspace(0,T,N))';          % make the time vector match the size of the data stream
                Fs = app.Fs;
                
                
                % save as mat file
                save(strcat(app.savepath,app.savefile,'.mat'),'timeStamps','audioOut','Fs');
                
            end
            
            if (app.SaveWav.Value)
                
                % save as wav file
                if (app.RawOut.Value)
                    audiowrite(strcat(app.savepath,app.RAF.Value,'.wav'),audioOut(:,1),app.Fs);
                end
                
                if(app.ProcessedOut.Value)
                    audiowrite(strcat(app.savepath,app.PAF.Value,'.wav'),audioOut(:,2),app.Fs);
                end
                
            end
            
            % diagnostic info lines part 2
            app.TNOS.Value = double(totalOverrun);
            app.TSO.Value = double(totalOverrun)/double(app.deviceReader.SampleRate);
            
            app.ReadyLamp.Color = 'green';
            
            
        end

        % Button pushed function: Stop
        function Stop_Callback(app, event)
            
            % stop the acquisition
            release(app.equalizer);
            release(app.deviceReader);
            
        end

        % Close request function: figure1
        function Model_ClosingFcn(app, event)
            
            answer = questdlg('Close app?','Exit');
            switch answer
                case 'Yes'
                    if isLocked(app.deviceReader)
                        release(app.deviceReader);
                        release(app.equalizer);
                        release(app.Buffer);
                    end
                case 'No'
                    return
                case 'Cancel'
                    return
            end
            delete(app)
        end

        % Value changing function: GainKnob, VolumeKnob
        function VolumeKnobValueChanging(app, event)
            app.Volume = app.VolumeKnob.Value*app.GainKnob.Value;
            
        end

        % Selection changed function: InputChannel
        function InputChannelSelectionChanged(app, event)
            
            if(app.LeftButton.Value)
                %   left channel selected
                app.deviceReader.ChannelMapping = 1;
            elseif (app.RightButton.Value)
                %   right channel selected
                app.deviceReader.ChannelMapping = 2;
            end
            
        end

        % Button pushed function: Import
        function ImportButtonPushed(app, event)
            % Check whether is first run 
            if app.lastpath == 0
                [filein,pathin] = uigetfile('*.wav','Select Audio File',app.inipath);
            else
                [filein,pathin] = uigetfile('*.wav','Select Audio File',app.lastpath);
            end
            
            
            %   To remember last open path
            if pathin ~= 0               
                app.lastpath = pathin;
            end


            if filein == 0
                return
            else
                
                
                %   Combine path
                inputfile = strcat(pathin,filein);
                
                [app.audioImport,app.Fs_Import] = audioread(inputfile);
                
                %   This section allows only mono input
                if ~iscolumn(app.audioImport)
                    if(app.LeftButton.Value)
                        app.audioImport = app.audioImport(:,1);
                    elseif (app.RightButton.Value)
                        app.audioImport = app.audioImport(:,2);
                    end
                end
                expression = strsplit(filein,'.wav','DelimiterType','RegularExpression');
                
                app.ImportFilename.Value = expression{1};
                
            end


        end

        % Button pushed function: Analyze
        function AnalyzeButtonPushed(app, event)
            
            if ~isempty(app.audioImport)
                
                idl = length(app.audioImport);
                
                timevec = 0:app.dt:(idl-1)/app.Fs_Import;
                
                
                app.audiotimevec = figure(1);
                app.audiotimevec.Name = 'Sound Signal';
                plot(timevec,app.audioImport);
                title('Sound Signal');
                xlabel('Time (seconds)');
                ylabel('Amplitude');
                axis(gca,'tight');
                
            end
            
            
        end

        % Selection changed function: SpectrumType
        function SpectrumTypeSelectionChanged(app, event)
            
            if (app.PowerSpectralDensityButton.Value)
                app.spectrumtype = 'psd';
            elseif (app.PowerButton.Value)
                app.spectrumtype = 'power';
            end
            SpectrogramButtonPushed(app,event);
        end

        % Button pushed function: SpectrogramButton
        function SpectrogramButtonPushed(app, event)
            %   since this function triggered from many events
            %   a identifier must be provided
            
            %   Selection changes is from 2 places, value changes is from 2 places
            %   Pressing this button also enable spectrogram creation
            
            checkEvent = strcmp(event.EventName,'ValueChanged')||strcmp(event.EventName,'SelectionChanged')||strcmp(event.Source.Text,'Spectrogram');


            if (checkEvent) && ~isempty(app.audioImport)
                
                %   Create Figure
                
                %   Plot spectrogram on current figure
                app.spectrofig = figure(2);
                app.spectrofig.Name = 'Spectrogram';
                
                spectrogram(app.audioImport,app.windowtype,app.overlap,[],app.Fs_Import,app.spectrumtype,'yaxis');
                app.spectroaxes = gca;
                title(app.spectroaxes,strcat('Recorded Sound Signal',{' '},'@',{' '},app.ImportFilename.Value));
                
                colormap(app.spectroaxes,jet);
                
                
                ylim(app.spectroaxes, [0,app.FreqLimitkHzSlider.Value])        % kHz, better between 0-10kHz
                
                app.FreqLimitkHzSliderLabel.Enable = 'on';
                app.FreqLimitkHzSlider.Enable = 'on';


            end
            
            
        end

        % Value changed function: WindowsLengthEditField
        function WindowsLengthEditFieldValueChanged(app, event)
            
            app.winlength = app.WindowsLengthEditField.Value;
            WindowFunctionsSelectionChanged(app, event)
            
        end

        % Selection changed function: WindowFunctions
        function WindowFunctionsSelectionChanged(app, event)
            
            switch app.WindowFunctions.SelectedObject
                
                case (app.HanningButton)
                    
                    app.windowtype = window(@hann,app.winlength);
                    
                case (app.HammingButton)
                    
                    app.windowtype = window(@hamming,app.winlength);
                    
                case (app.KasierButton)
                    
                    app.windowtype = window(@kaiser,app.winlength,3);
                    
                case (app.RectangularButton)
                    
                    app.windowtype = window(@rectwin,app.winlength);
                    
                case (app.BartlettButton)
                    
                    app.windowtype = window(@bartlett,app.winlength);
                    
                case (app.BlackmanButton)
                    
                    app.windowtype = window(@blackman,app.winlength);
                    
                case (app.BlackmanharrisButton)
                    
                    app.windowtype = window(@blackmanharris,app.winlength);
                    
                case (app.BarthannwinButton)
                    
                    app.windowtype = window(@barthannwin,app.winlength);
                    
            end
            OverlapValueChanged(app,event)

            
        end

        % Value changing function: FreqLimitkHzSlider
        function FreqLimitkHzSliderValueChanging(app, event)
            ylim(app.spectroaxes, [0,app.FreqLimitkHzSlider.Value])        % kHz, better between 0-10kHz
            
        end

        % Value changed function: FreqLimitkHzSlider
        function FreqLimitkHzSliderValueChanged(app, event)
            ylim(app.spectroaxes, [0,app.FreqLimitkHzSlider.Value])        % kHz, better between 0-10kHz
        end

        % Value changed function: SamplingRate
        function SamplingRateValueChanged(app, event)
            app.Fs = app.SamplingRate.Value;
            if isLocked(app.timeScope)
                release(app.timeScope)
                release(app.Spectrum)
                release(app.timeScope)
            end
            %             Reset all objects using sample rate as reference
            app.timeScope.SampleRate = app.Fs;
            app.Spectrum.SampleRate = app.Fs;
            app.deviceReader.SampleRate = app.Fs;
            app.equalizer.SampleRate = app.Fs;
            app.dt=1/app.Fs;
        end

        % Value changed function: SaveBox, SaveWav
        function SaveWavValueChanged(app, event)
            
            if (app.SaveWav.Value) || (app.SaveBox.Value)
                app.NormalizeCheckBox.Enable = 'on';
            else
                app.NormalizeCheckBox.Enable = 'off';
            end
                        
        end

        % Selection changed function: AudioDevice
        function AudioDeviceSelectionChanged(app, event)
            %   Manual way changing recording device
            
            if (app.MicButton.Value)
                app.deviceReader.Device = 'Primary Sound Capture Driver';
                
            elseif (app.LineInButton.Value)
                app.deviceReader.Device = 'Line In (Realtek High Definition Audio)';
                
            end
            
            
        end

        % Value changed function: WindowsLengthDropDown
        function WindowsLengthDropDownValueChanged(app, event)
            app.winlength = str2double(app.WindowsLengthDropDown.Value);
            app.WindowsLengthEditField.Value = app.winlength;
            WindowFunctionsSelectionChanged(app, event)
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            
            [B,A] = coeffs(app.equalizer);
            % Compute the response using the SOS argument format for coefficients
            [h,f] = freqz([B;ones(1,size(B,2));A]',4096,app.equalizer.SampleRate);
            plot(app.EqualizerSpec,f,db(abs(h)));
            
            
        end

        % Value changed function: Design_method, Design_method_2, 
        % Design_method_3, Design_method_4
        function Design_methodValueChanged(app, event)
            switch app.Design_method.Value
                %   IIR filters
                
                case 'Butterworth'
                    app.FilterMatchExactly = 'stopband';
                    app.FilterDesignMethod = 'butter';
                    
                case 'Chebyshev Type I'
                    app.FilterMatchExactly = 'passband';
                    app.FilterDesignMethod = 'cheby1';
                    
                case 'Chebyshev Type II'
                    app.FilterMatchExactly = 'stopband';
                    app.FilterDesignMethod = 'cheby2';
                    
                case 'Elliptic'
                    app.FilterMatchExactly = 'both';
                    app.FilterDesignMethod = 'ellip';
                    
            end
            
            
        end

        % Button pushed function: CreateBPF, CreateFilters, 
        % CreateHPF, CreateNF
        function CreateFiltersButtonPushed(app, event)
            filterdesign(app,event.Source.Parent.Title)
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            cla(app.UIFiltSpec);
            app.FiltersCascadedLamp.Color = 'red';
            
            %           check conditions of filter bank availability
            
            %           minimum of 2 filters are required to be cascaded
            
            try
                if length(app.FilterBank) < 2
                    %   In fact this is the default filter mode, only 1 to be used
                    app.FC = app.FilterBank{1};
                    %                     warning('Not enough filters to be cascaded!');
                    
                    %           if no new cascaded filters we can create it
                    
                elseif length(app.FilterBank) == 2
                    
                    app.FC = cascade(app.FilterBank{1},app.FilterBank{2});
                    
                    %           if there are subsequent filters in filterbank list, add them into the list
                elseif  length(app.FilterBank) > 2


                    app.FC = cascade(app.FilterBank{1},app.FilterBank{2});
                    
                    n = 3;
                    
                    while getNumStages(app.FC) < length(app.FilterBank)
                        
                        addStage(app.FC,app.FilterBank{n})
                        n = n + 1;
                    end


                end
                
                %                 display the filter properties
                NFFT = 2^10;
                app.freqaxis = app.Fs/2*linspace(0,1,NFFT/2+1);
                
                [h,f] = freqz(app.FC,app.freqaxis,app.Fs) ;
                plot(app.UIFiltSpec,f,db(abs(h)));
                app.FiltersCascadedLamp.Color = 'green';
                
            catch ME
                
                warning(ME)
                
            end
            
            
        end

        % Button pushed function: Clear_View
        function Clear_ViewButtonPushed(app, event)
            %             clear all relevant parameters under View tab
            %             Does not trigger the creation of spectrogram since audio was cleared
            
            %   clear audio no matter what to ensure wont trigger the procedure
            app.audioImport = [];
            app.ImportFilename.Value = '';
            app.Fs_Import = '';
            
            app.PowerSpectralDensityButton.Value = 1;
            SpectrumTypeSelectionChanged(app, event)
            
            
            app.WindowsLengthDropDown.Value = '1024';
            WindowsLengthDropDownValueChanged(app, event)
            
            app.WindowsLengthEditField.Value = 1024;
            WindowsLengthEditFieldValueChanged(app, event);
            
            app.HanningButton.Value = 1;
            WindowFunctionsSelectionChanged(app, event);
            
            
            app.Overlap.Value = 50;
            OverlapValueChanged(app,event)
            
            app.FreqLimitkHzSlider.Value = 3;
            app.FreqLimitkHzSlider.Enable = 'off';
            app.FreqLimitkHzSliderLabel.Enable = 'off';
            
            delete(findobj('Name','Sound Signal'));
            delete(findobj('Name','Spectrogram'));
            
            
        end

        % Button pushed function: Clear_Filter
        function Clear_FilterButtonPushed(app, event)
            %   clearing and reinitialize parameters under filter tab
            
            app.FilterBank = {};
            app.FiltersAppliedListBox.Items = {};
            app.FC = [];
            app.filtercount = 1;
            app.lpfc = 1;
            app.hpfc = 1;
            app.bpfc = 1;
            app.nfc  = 1;
            filterdesign(app)
            OKButtonPushed(app)
        end

        % Button pushed function: Clear_EQ
        function Clear_EQButtonPushed(app, event)
            clearEQparameters(app)
            VisualizeButtonPushed(app)
        end

        % Value changed function: Overlap
        function OverlapValueChanged(app, event)
            
            value = app.Overlap.Value;
            app.overlap = app.winlength*value/100;
            app.overlap = round(app.overlap);
            SpectrogramButtonPushed(app,event)
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            
            switch selectedTab.Title
                
                case 'Main'
                    
                case 'Filters'
                    
                    NFFT = 2^12;
                    app.freqaxis = app.Fs/2*linspace(0,1,NFFT/2+1);
                    
                    %                 case 'Equalizer'
                    %                 case 'Input'
                    %                 case 'Output'
                    
                case 'Equalizer'
                    
                    VisualizeButtonPushed(app)
                    
                    
            end
        end

        % Value changed function: EQGain_1, EQGain_10, EQGain_2, 
        % EQGain_3, EQGain_4, EQGain_5, EQGain_6, EQGain_7, EQGain_8, 
        % EQGain_9
        function EQGain_ValueChanged(app, event)
            switch event.Source.Tag
                case 'EQGain_1'
                    app.equalizer.Gains(1) = app.EQGain_1.Value;
                case 'EQGain_2'
                    app.equalizer.Gains(2) = app.EQGain_2.Value;
                case 'EQGain_3'
                    app.equalizer.Gains(3) = app.EQGain_3.Value;
                case 'EQGain_4'
                    app.equalizer.Gains(4) = app.EQGain_4.Value;
                case 'EQGain_5'
                    app.equalizer.Gains(5) = app.EQGain_5.Value;
                case 'EQGain_6'
                    app.equalizer.Gains(6) = app.EQGain_6.Value;
                case 'EQGain_7'
                    app.equalizer.Gains(7) = app.EQGain_7.Value;
                case 'EQGain_8'
                    app.equalizer.Gains(8) = app.EQGain_8.Value;
                case 'EQGain_9'
                    app.equalizer.Gains(9) = app.EQGain_9.Value;
                case 'EQGain_10'
                    app.equalizer.Gains(10) = app.EQGain_10.Value;
            end
            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            %   Provide Save Files Location and details
            filname = [app.inipath 'Output Data.mat'];
            [app.savefile,app.savepath] = uiputfile(filname,'Select Output Folder');
            path = app.savepath;
            
            if isequal(app.savepath,0)
                app.OutputFolderEditField.Value = 'Please choose a output folder !';
                %   Reset Save file name to ensure the recorded files can be saved 
                app.savefile = 'Output Data';
                %   Reset Save file path where DESKTOP as default directory to ensure at least desktop is chosen
                app.savepath = app.inipath;
                path = "Desktop as Default Folder";
            end
            app.OutputFolderEditField.Value = path;
        end

        % Button pushed function: ExportasImageButton
        function ExportasImageButtonPushed(app, event)
            getimage
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'Audio Recording App';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @Model_ClosingFcn, true);
            app.AppTitle = uilabel(app.figure1);
            app.AppTitle.Text = 'Audio Analysis App';
            app.TabGroup = uitabgroup(app.figure1);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.Clear_Main = uibutton(app.MainTab, 'push');
            app.Clear_Main.ButtonPushedFcn = createCallbackFcn(app, @Clear_Main_Callback, true);
            app.Clear_Main.Text = 'Clear';
            app.Stop = uibutton(app.MainTab, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @Stop_Callback, true);
            app.Stop.Text = 'Stop';
            app.Start = uibutton(app.MainTab, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @Start_Callback, true);
            app.Start.Text = 'Start';
            app.SaveBox = uicheckbox(app.MainTab);
            app.SaveBox.ValueChangedFcn = createCallbackFcn(app, @SaveWavValueChanged, true);
            app.SaveBox.Text = 'Save as .mat File';
            app.SaveWav = uicheckbox(app.MainTab);
            app.SaveWav.ValueChangedFcn = createCallbackFcn(app, @SaveWavValueChanged, true);
            app.SaveWav.Text = 'Save as .wav File';
            app.VolumeKnobLabel = uilabel(app.MainTab);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.MainTab, 'continuous');
            app.VolumeKnob.Limits = [0 10];
            app.VolumeKnob.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.VolumeKnob.ValueChangingFcn = createCallbackFcn(app, @VolumeKnobValueChanging, true);
            app.VolumeKnob.MinorTicks = [];
            app.VolumeKnob.Value = 1;
            app.ElapsedTimesEditFieldLabel = uilabel(app.MainTab);
            app.ElapsedTimesEditFieldLabel.Text = 'Elapsed Time (s)';
            app.ElapsedTime = uieditfield(app.MainTab, 'numeric');
            app.ElapsedTime.Editable = 'off';
            app.GainKnobLabel = uilabel(app.MainTab);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.MainTab, 'continuous');
            app.GainKnob.Limits = [0 10];
            app.GainKnob.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.GainKnob.ValueChangingFcn = createCallbackFcn(app, @VolumeKnobValueChanging, true);
            app.GainKnob.MinorTicks = [];
            app.GainKnob.Value = 1;
            app.NormalizeCheckBox = uicheckbox(app.MainTab);
            app.NormalizeCheckBox.Enable = 'off';
            app.NormalizeCheckBox.Text = 'Normalize';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.OKButton = uibutton(app.FiltersTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FiltersAppliedListBoxLabel = uilabel(app.FiltersTab);
            app.FiltersAppliedListBoxLabel.Text = 'Filters Applied';
            app.FiltersAppliedListBox = uilistbox(app.FiltersTab);
            app.FiltersAppliedListBox.Items = {};
            app.FiltersAppliedListBox.Multiselect = 'on';
            app.FiltersAppliedListBox.Value = {};
            app.TabGroup2 = uitabgroup(app.FiltersTab);
            app.LowPassTab = uitab(app.TabGroup2);
            app.LowPassTab.Title = 'Low Pass';
            app.FstopEditFieldLabel = uilabel(app.LowPassTab);
            app.FstopEditFieldLabel.Text = 'Fstop';
            app.FstopLPF = uieditfield(app.LowPassTab, 'numeric');
            app.FstopLPF.Value = 6000;
            app.FpassEditFieldLabel = uilabel(app.LowPassTab);
            app.FpassEditFieldLabel.Text = 'Fpass';
            app.FpassLPF = uieditfield(app.LowPassTab, 'numeric');
            app.FpassLPF.Value = 3500;
            app.CreateFilters = uibutton(app.LowPassTab, 'push');
            app.CreateFilters.ButtonPushedFcn = createCallbackFcn(app, @CreateFiltersButtonPushed, true);
            app.CreateFilters.Text = 'Create Filter';
            app.DesignMethodDropDown_8Label_2 = uilabel(app.LowPassTab);
            app.DesignMethodDropDown_8Label_2.Text = 'Design Method';
            app.Design_method_2 = uidropdown(app.LowPassTab);
            app.Design_method_2.Items = {'Butterworth', 'Chebyshev Type I', 'Chebyshev Type II', 'Elliptic'};
            app.Design_method_2.ValueChangedFcn = createCallbackFcn(app, @Design_methodValueChanged, true);
            app.Design_method_2.Value = 'Elliptic';
            app.HighPassTab = uitab(app.TabGroup2);
            app.HighPassTab.Title = 'High Pass';
            app.CreateHPF = uibutton(app.HighPassTab, 'push');
            app.CreateHPF.ButtonPushedFcn = createCallbackFcn(app, @CreateFiltersButtonPushed, true);
            app.CreateHPF.Text = 'Create Filter';
            app.FstopEditField_2Label = uilabel(app.HighPassTab);
            app.FstopEditField_2Label.Text = 'Fstop';
            app.FstopHPF = uieditfield(app.HighPassTab, 'numeric');
            app.FstopHPF.Value = 50;
            app.FpassEditField_2Label = uilabel(app.HighPassTab);
            app.FpassEditField_2Label.Text = 'Fpass';
            app.FpassHPF = uieditfield(app.HighPassTab, 'numeric');
            app.FpassHPF.Value = 101;
            app.DesignMethodDropDown_8Label_3 = uilabel(app.HighPassTab);
            app.DesignMethodDropDown_8Label_3.Text = 'Design Method';
            app.Design_method_3 = uidropdown(app.HighPassTab);
            app.Design_method_3.Items = {'Butterworth', 'Chebyshev Type I', 'Chebyshev Type II', 'Elliptic'};
            app.Design_method_3.ValueChangedFcn = createCallbackFcn(app, @Design_methodValueChanged, true);
            app.Design_method_3.Value = 'Elliptic';
            app.BandPassTab = uitab(app.TabGroup2);
            app.BandPassTab.Title = 'Band Pass';
            app.CreateBPF = uibutton(app.BandPassTab, 'push');
            app.CreateBPF.ButtonPushedFcn = createCallbackFcn(app, @CreateFiltersButtonPushed, true);
            app.CreateBPF.Text = 'Create Filter';
            app.Fstop1EditFieldLabel = uilabel(app.BandPassTab);
            app.Fstop1EditFieldLabel.Text = 'Fstop1';
            app.Fstop1BPF = uieditfield(app.BandPassTab, 'numeric');
            app.Fstop1BPF.Value = 50;
            app.Fpass2Label = uilabel(app.BandPassTab);
            app.Fpass2Label.Text = 'Fpass2';
            app.Fpass2BPF = uieditfield(app.BandPassTab, 'numeric');
            app.Fpass2BPF.Value = 3500;
            app.Fstop2BPF = uieditfield(app.BandPassTab, 'numeric');
            app.Fstop2BPF.Value = 6000;
            app.Fstop2EditFieldLabel = uilabel(app.BandPassTab);
            app.Fstop2EditFieldLabel.Text = 'Fstop2';
            app.Fpass1BPF = uieditfield(app.BandPassTab, 'numeric');
            app.Fpass1BPF.Value = 101;
            app.Fpass1EditField_2Label = uilabel(app.BandPassTab);
            app.Fpass1EditField_2Label.Text = 'Fpass1';
            app.DesignMethodDropDown_8Label_4 = uilabel(app.BandPassTab);
            app.DesignMethodDropDown_8Label_4.Text = 'Design Method';
            app.Design_method_4 = uidropdown(app.BandPassTab);
            app.Design_method_4.Items = {'Butterworth', 'Chebyshev Type I', 'Chebyshev Type II', 'Elliptic'};
            app.Design_method_4.ValueChangedFcn = createCallbackFcn(app, @Design_methodValueChanged, true);
            app.Design_method_4.Value = 'Elliptic';
            app.NotchTab = uitab(app.TabGroup2);
            app.NotchTab.Title = 'Notch';
            app.CreateNF = uibutton(app.NotchTab, 'push');
            app.CreateNF.ButtonPushedFcn = createCallbackFcn(app, @CreateFiltersButtonPushed, true);
            app.CreateNF.Text = 'Create Filter';
            app.FnotchEditFieldLabel = uilabel(app.NotchTab);
            app.FnotchEditFieldLabel.Text = 'Fnotch';
            app.FnotchEditField = uieditfield(app.NotchTab, 'numeric');
            app.FnotchEditField.Value = 50;
            app.Design_Method = uilabel(app.NotchTab);
            app.Design_Method.Text = 'Design Method';
            app.Design_method = uidropdown(app.NotchTab);
            app.Design_method.Items = {'Butterworth', 'Chebyshev Type I', 'Chebyshev Type II', 'Elliptic'};
            app.Design_method.ValueChangedFcn = createCallbackFcn(app, @Design_methodValueChanged, true);
            app.Design_method.Value = 'Elliptic';
            app.BandwidthEditFieldLabel = uilabel(app.NotchTab);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.NotchTab, 'numeric');
            app.BandwidthEditField.Value = 10;
            app.UIFiltSpec = uiaxes(app.FiltersTab);
            title(app.UIFiltSpec, 'Filter Frequency Response')
            xlabel(app.UIFiltSpec, 'Frequency (Hz)')
            app.Clear_Filter = uibutton(app.FiltersTab, 'push');
            app.Clear_Filter.ButtonPushedFcn = createCallbackFcn(app, @Clear_FilterButtonPushed, true);
            app.Clear_Filter.Text = 'Clear';
            app.FiltersCascadedLampLabel = uilabel(app.FiltersTab);
            app.FiltersCascadedLampLabel.Text = 'Filters Cascaded';
            app.FiltersCascadedLamp = uilamp(app.FiltersTab);
            app.EqualizerTab = uitab(app.TabGroup);
            app.EqualizerTab.Title = 'Equalizer';
            app.EqualizerSpec = uiaxes(app.EqualizerTab);
            xlabel(app.EqualizerSpec, 'Frequency(Hz)')
            ylabel(app.EqualizerSpec, 'Response(dB)')
            app.EqualizerSpec.XLim = [20 20000];
            app.EqualizerSpec.XScale = 'log';
            app.GraphicEQPanel = uipanel(app.EqualizerTab);
            app.GraphicEQPanel.Title = 'Graphic EQ Panel';
            app.HzSliderLabel = uilabel(app.GraphicEQPanel);
            app.HzSliderLabel.Text = '31.5Hz';
            app.EQGain_1 = uislider(app.GraphicEQPanel);
            app.EQGain_1.Limits = [-60 20];
            app.EQGain_1.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_1.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_1.MinorTicks = [];
            app.HzSlider_2Label = uilabel(app.GraphicEQPanel);
            app.HzSlider_2Label.Text = '63Hz';
            app.EQGain_2 = uislider(app.GraphicEQPanel);
            app.EQGain_2.Limits = [-60 20];
            app.EQGain_2.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_2.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_2.MinorTicks = [];
            app.HzSlider_3Label = uilabel(app.GraphicEQPanel);
            app.HzSlider_3Label.Text = '125Hz';
            app.EQGain_3 = uislider(app.GraphicEQPanel);
            app.EQGain_3.Limits = [-60 20];
            app.EQGain_3.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_3.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_3.MinorTicks = [];
            app.HzSlider_4Label = uilabel(app.GraphicEQPanel);
            app.HzSlider_4Label.Text = '250Hz';
            app.EQGain_4 = uislider(app.GraphicEQPanel);
            app.EQGain_4.Limits = [-60 20];
            app.EQGain_4.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_4.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_4.MinorTicks = [];
            app.HzSlider_5Label = uilabel(app.GraphicEQPanel);
            app.HzSlider_5Label.Text = '500Hz';
            app.EQGain_5 = uislider(app.GraphicEQPanel);
            app.EQGain_5.Limits = [-60 20];
            app.EQGain_5.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_5.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_5.MinorTicks = [];
            app.HzSlider_6Label = uilabel(app.GraphicEQPanel);
            app.HzSlider_6Label.Text = '1000Hz';
            app.EQGain_6 = uislider(app.GraphicEQPanel);
            app.EQGain_6.Limits = [-60 20];
            app.EQGain_6.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_6.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_6.MinorTicks = [];
            app.HzSlider_7Label = uilabel(app.GraphicEQPanel);
            app.HzSlider_7Label.Text = '2000Hz';
            app.EQGain_7 = uislider(app.GraphicEQPanel);
            app.EQGain_7.Limits = [-60 20];
            app.EQGain_7.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_7.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_7.MinorTicks = [];
            app.HzSlider_8Label = uilabel(app.GraphicEQPanel);
            app.HzSlider_8Label.Text = '4000Hz';
            app.EQGain_8 = uislider(app.GraphicEQPanel);
            app.EQGain_8.Limits = [-60 20];
            app.EQGain_8.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_8.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_8.MinorTicks = [];
            app.HzLabel = uilabel(app.GraphicEQPanel);
            app.HzLabel.Text = '8000Hz';
            app.EQGain_9 = uislider(app.GraphicEQPanel);
            app.EQGain_9.Limits = [-60 20];
            app.EQGain_9.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_9.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_9.MinorTicks = [];
            app.HzSlider_10Label = uilabel(app.GraphicEQPanel);
            app.HzSlider_10Label.Text = '16000Hz';
            app.EQGain_10 = uislider(app.GraphicEQPanel);
            app.EQGain_10.Limits = [-60 20];
            app.EQGain_10.MajorTickLabels = {'-60', '', '', '', '', '20'};
            app.EQGain_10.ValueChangedFcn = createCallbackFcn(app, @EQGain_ValueChanged, true);
            app.EQGain_10.MinorTicks = [];
            app.VisualizeButton = uibutton(app.GraphicEQPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.Clear_EQ = uibutton(app.GraphicEQPanel, 'push');
            app.Clear_EQ.ButtonPushedFcn = createCallbackFcn(app, @Clear_EQButtonPushed, true);
            app.Clear_EQ.Text = 'Clear';
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.InputChannel = uibuttongroup(app.InputTab);
            app.InputChannel.SelectionChangedFcn = createCallbackFcn(app, @InputChannelSelectionChanged, true);
            app.InputChannel.Title = 'Input Channel Selection';
            app.LeftButton = uiradiobutton(app.InputChannel);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.InputChannel);
            app.RightButton.Text = 'Right';
            app.SamplingRateEditFieldLabel = uilabel(app.InputTab);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRate = uieditfield(app.InputTab, 'numeric');
            app.SamplingRate.ValueDisplayFormat = '%.0f';
            app.SamplingRate.ValueChangedFcn = createCallbackFcn(app, @SamplingRateValueChanged, true);
            app.SamplingRate.Value = 44100;
            app.AudioDevice = uibuttongroup(app.InputTab);
            app.AudioDevice.SelectionChangedFcn = createCallbackFcn(app, @AudioDeviceSelectionChanged, true);
            app.AudioDevice.Title = 'Audio Device';
            app.MicButton = uiradiobutton(app.AudioDevice);
            app.MicButton.Text = 'Mic';
            app.MicButton.Value = true;
            app.LineInButton = uiradiobutton(app.AudioDevice);
            app.LineInButton.Enable = 'off';
            app.LineInButton.Text = 'Line In';
            app.Record_Driver_Tag = uilabel(app.InputTab);
            app.Record_Driver_Tag.Text = {'Recording Driver'; ''};
            app.Record_Driver = uidropdown(app.InputTab);
            app.Record_Driver.Items = {'DirectSound', 'WASAPI'};
            app.Record_Driver.ValueChangedFcn = createCallbackFcn(app, @Record_Driver_Callback, true);
            app.Record_Driver.Value = 'DirectSound';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.DiagnosticsParametersPanel = uipanel(app.OutputTab);
            app.DiagnosticsParametersPanel.Title = 'Diagnostics Parameters';
            app.TotalNumberofOverrunSamplesEditFieldLabel = uilabel(app.DiagnosticsParametersPanel);
            app.TotalNumberofOverrunSamplesEditFieldLabel.Text = 'Total Number of Overrun Samples';
            app.TNOS = uieditfield(app.DiagnosticsParametersPanel, 'numeric');
            app.TNOS.ValueDisplayFormat = '%.0f';
            app.TNOS.Editable = 'off';
            app.TotalSecondsOverrunEditFieldLabel = uilabel(app.DiagnosticsParametersPanel);
            app.TotalSecondsOverrunEditFieldLabel.Text = 'Total Seconds Overrun';
            app.TSO = uieditfield(app.DiagnosticsParametersPanel, 'numeric');
            app.TSO.Editable = 'off';
            app.RawAudioFilenameEditFieldLabel = uilabel(app.OutputTab);
            app.RawAudioFilenameEditFieldLabel.Text = 'Raw Audio Filename';
            app.RAF = uieditfield(app.OutputTab, 'text');
            app.RAF.Value = 'Raw';
            app.ProcessedAudioFilenameEditFieldLabel = uilabel(app.OutputTab);
            app.ProcessedAudioFilenameEditFieldLabel.Text = 'Processed Audio Filename';
            app.PAF = uieditfield(app.OutputTab, 'text');
            app.PAF.Value = 'Processed';
            app.OutputFilesPanel = uipanel(app.OutputTab);
            app.OutputFilesPanel.Title = 'Output Files';
            app.RawOut = uicheckbox(app.OutputFilesPanel);
            app.RawOut.Text = 'Raw';
            app.RawOut.Value = true;
            app.ProcessedOut = uicheckbox(app.OutputFilesPanel);
            app.ProcessedOut.Text = 'Processed';
            app.ProcessedOut.Value = true;
            app.OutputFolderEditFieldLabel = uilabel(app.OutputTab);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.OutputTab, 'text');
            app.OutputFolderEditField.Editable = 'off';
            app.OutputFolderEditField.Value = 'Desktop as Default Folder';
            app.SelectFolderButton = uibutton(app.OutputTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.Analyze = uibutton(app.ViewTab, 'push');
            app.Analyze.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.Analyze.Text = 'Analyze';
            app.Import = uibutton(app.ViewTab, 'push');
            app.Import.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.Import.Text = 'Import';
            app.ImportFilenameLabel = uilabel(app.ViewTab);
            app.ImportFilenameLabel.Text = 'Filename';
            app.ImportFilename = uieditfield(app.ViewTab, 'text');
            app.SpectrumType = uibuttongroup(app.ViewTab);
            app.SpectrumType.SelectionChangedFcn = createCallbackFcn(app, @SpectrumTypeSelectionChanged, true);
            app.SpectrumType.Title = 'Spectrogram spectrum Type';
            app.PowerSpectralDensityButton = uiradiobutton(app.SpectrumType);
            app.PowerSpectralDensityButton.Text = 'Power Spectral Density';
            app.PowerSpectralDensityButton.Value = true;
            app.PowerButton = uiradiobutton(app.SpectrumType);
            app.PowerButton.Text = 'Power';
            app.SpectrogramButton = uibutton(app.ViewTab, 'push');
            app.SpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramButtonPushed, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.OverlapLabel = uilabel(app.ViewTab);
            app.OverlapLabel.Text = 'Overlap %';
            app.Overlap = uieditfield(app.ViewTab, 'numeric');
            app.Overlap.ValueDisplayFormat = '%.0f';
            app.Overlap.ValueChangedFcn = createCallbackFcn(app, @OverlapValueChanged, true);
            app.Overlap.Value = 50;
            app.WindowFunctions = uibuttongroup(app.ViewTab);
            app.WindowFunctions.SelectionChangedFcn = createCallbackFcn(app, @WindowFunctionsSelectionChanged, true);
            app.WindowFunctions.Title = 'Available Window Functions';
            app.HanningButton = uiradiobutton(app.WindowFunctions);
            app.HanningButton.Text = 'Hanning';
            app.HanningButton.Value = true;
            app.HammingButton = uiradiobutton(app.WindowFunctions);
            app.HammingButton.Text = 'Hamming';
            app.KasierButton = uiradiobutton(app.WindowFunctions);
            app.KasierButton.Text = 'Kasier';
            app.RectangularButton = uiradiobutton(app.WindowFunctions);
            app.RectangularButton.Text = 'Rectangular';
            app.BartlettButton = uiradiobutton(app.WindowFunctions);
            app.BartlettButton.Text = 'Bartlett';
            app.BlackmanButton = uiradiobutton(app.WindowFunctions);
            app.BlackmanButton.Text = 'Blackman';
            app.BlackmanharrisButton = uiradiobutton(app.WindowFunctions);
            app.BlackmanharrisButton.Text = 'Blackmanharris';
            app.BarthannwinButton = uiradiobutton(app.WindowFunctions);
            app.BarthannwinButton.Text = 'Barthannwin';
            app.FreqLimitkHzSliderLabel = uilabel(app.ViewTab);
            app.FreqLimitkHzSliderLabel.Enable = 'off';
            app.FreqLimitkHzSliderLabel.Text = 'Freq Limit (kHz)';
            app.FreqLimitkHzSlider = uislider(app.ViewTab);
            app.FreqLimitkHzSlider.Limits = [3 20];
            app.FreqLimitkHzSlider.ValueChangedFcn = createCallbackFcn(app, @FreqLimitkHzSliderValueChanged, true);
            app.FreqLimitkHzSlider.ValueChangingFcn = createCallbackFcn(app, @FreqLimitkHzSliderValueChanging, true);
            app.FreqLimitkHzSlider.Enable = 'off';
            app.FreqLimitkHzSlider.Value = 3;
            app.Panel = uipanel(app.ViewTab);
            app.WindowsLengthDropDownLabel = uilabel(app.Panel);
            app.WindowsLengthDropDownLabel.Text = 'Windows Length';
            app.WindowsLengthDropDown = uidropdown(app.Panel);
            app.WindowsLengthDropDown.Items = {'128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536'};
            app.WindowsLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowsLengthDropDownValueChanged, true);
            app.WindowsLengthDropDown.Value = '1024';
            app.WindowsLengthEditField = uieditfield(app.Panel, 'numeric');
            app.WindowsLengthEditField.ValueDisplayFormat = '%.0f';
            app.WindowsLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsLengthEditFieldValueChanged, true);
            app.WindowsLengthEditField.Value = 1024;
            app.Clear_View = uibutton(app.ViewTab, 'push');
            app.Clear_View.ButtonPushedFcn = createCallbackFcn(app, @Clear_ViewButtonPushed, true);
            app.Clear_View.Text = 'Clear';
            app.ExportasImageButton = uibutton(app.ViewTab, 'push');
            app.ExportasImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasImageButtonPushed, true);
            app.ExportasImageButton.Text = 'Export as Image';
            app.ReadyLamp = uilamp(app.figure1);
            app.ReadyLampLabel = uilabel(app.figure1);
            app.ReadyLampLabel.Text = 'Ready';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Model_App_Latest

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Model_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end